classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        ButtonGroup_Contrast          matlab.ui.container.ButtonGroup
        Contrast_Linear               matlab.ui.control.ToggleButton
        Contrast_Log                  matlab.ui.control.ToggleButton
        Contrast_Index                matlab.ui.control.ToggleButton
        Input_Image                   matlab.ui.control.EditField
        ButtonGroup_Histogram         matlab.ui.container.ButtonGroup
        Histogram_Origin              matlab.ui.control.ToggleButton
        Histogram_Equalization        matlab.ui.control.ToggleButton
        Histogram_Perspective         matlab.ui.control.ToggleButton
        ButtonGroup_Selection         matlab.ui.container.ButtonGroup
        Histogram                     matlab.ui.control.ToggleButton
        Contrast                      matlab.ui.control.ToggleButton
        Button_6                      matlab.ui.control.ToggleButton
        Button_5                      matlab.ui.control.ToggleButton
        Button_4                      matlab.ui.control.ToggleButton
        Button_3                      matlab.ui.control.ToggleButton
        Input_Label                   matlab.ui.control.Label
        Input_Button                  matlab.ui.control.Button
        Input_Histogram_ReferImage    matlab.ui.control.EditField
        UIAxes_Image                  matlab.ui.control.UIAxes
        UIAxes_Histogram_Image        matlab.ui.control.UIAxes
        UIAxes_Histogram_Histogram    matlab.ui.control.UIAxes
        UIAxes_Contrast_Grayimage     matlab.ui.control.UIAxes
        UIAxes_Contrast_Advanceimage  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img = imread("E:\course\Digital Image Processing\work\image.png")% 原始图像
        img_gray % 灰度图
        
        histogram % 灰度直方图
        histogram_equalized % 均衡化直方图
        histogram_perspected % 规定化直方图
        img_equalized % 均衡化图像
        img_perspected % 规定化图像
        
        img_advancedlinear % 线性变化增强图像
        img_advancedlog % 对数变化增强图像
        img_advancedindex % 指数变化增强图像_
    end


    methods (Access = private)
        
        % 重置所有组件
        function [] = reflush(app)
            reflush_histogram(app,0);
            reflush_contrast(app,0);
        end
        
        % 重置直方图相关组件
        function [] = reflush_histogram(app,flag)
            if(~flag)
                app.ButtonGroup_Histogram.Visible = false;
                app.Input_Histogram_ReferImage.Visible = false;
                app.UIAxes_Histogram_Image.Visible = false;
                app.UIAxes_Histogram_Histogram.Visible = false;
            else
                app.ButtonGroup_Histogram.Visible = true;
                app.Input_Histogram_ReferImage.Visible = true;
                app.UIAxes_Histogram_Image.Visible = true;
                app.UIAxes_Histogram_Histogram.Visible = true;
            end
        end
        
        % 重置对比度相关组件
        function [] = reflush_contrast(app,flag)
            if(~flag)
                app.ButtonGroup_Contrast.Visible = false;
                app.UIAxes_Contrast_Grayimage.Visible = false;
                app.UIAxes_Contrast_Advanceimage.Visible = false;
            else
                app.ButtonGroup_Contrast.Visible = true;
                app.UIAxes_Contrast_Grayimage.Visible = true;
                app.UIAxes_Contrast_Advanceimage.Visible = true;
            end
        end
        
    end
    
    methods (Access = public)
        
        % 灰度处理
        function image_gray = to_gray(~,image) 
            % r = image(:,:,1);% 分颜色通道提取数据  
            % g = image(:,:,2);
            % b = image(:,:,3);
            % Image_Gray = 0.299*r + 0.587*g + 0.114*b;% 加权处理后累加
            image_gray = rgb2gray(image);
        end
        
        % 灰度直方图
        function histogram = to_histogram(~,gray_image)
            histogram = zeros(256);
            [h,w] = size(gray_image);
            for x = 1:w
                for y = 1:h
                    histogram(gray_image(y,x)+1) = histogram(gray_image(y,x)+1)+1;
                end
            end
        end
        
        % 直方图均衡化
        function [equal_image,histogram] = to_histogram_equalized(~,gray_image)
            histgram = imhist(gray_image);
            [h,w] = size(gray_image);
            New_Image = zeros(h,w);
            s = zeros(256);
            s(1) =histgram(1);
            
            for t = 2:256
                s(t) = s(t-1) + histgram(t); % 计算新的灰度值
            end
            
            for x = 1:w
                for y= 1:h
                    New_Image(y,x) = s(gray_image(y,x)+1) / (w*h); % 生成新图像
                end
            end
            New_Image = uint8(New_Image*255);
            
            equal_image = New_Image;
            histogram = imhist(New_Image); 
        end
        
        % 直方图规定化
        function [histogram,persp_image] = to_histogram_perspected(~,gray_image,ref_image)
            input_img = gray_image;
            ref_img = ref_image;
            % 如果图像是逻辑类型，则转换为uint8类型
            if islogical(input_img)
                input_img = uint8(double(input_img) * 255);
            end
            if islogical(ref_img)
                ref_img = uint8(double(ref_img) * 255);
            end
            
            % 计算输入图像和参考图像的直方图
            [input_counts, ~] = imhist(input_img);
            [ref_counts, ref_bins] = imhist(ref_img);          
            % 累加直方图以得到累积分布函数
            input_cdf = cumsum(input_counts) / numel(input_img);
            ref_cdf = cumsum(ref_counts) / numel(ref_img);
            
            % 将输入图像的像素值映射到新的像素值
            table = zeros(1, 256);
            j = 1;
            for i = 1:256
               while j <= 256 && ref_cdf(j) < input_cdf(i)
                   j = j + 1;
               end
               table(i) = ref_bins(j-1);
            end
            
            persp_image = uint8(table(double(input_img) + 1)); % 应用查找表到输入图像
            histogram = imhist(persp_image);
        end
        
        % 线性变换对比度增强
        function advanced_image = to_enhanced_linear(~,gray_image)
            [h,w]=size(gray_image);
            advanced_image = zeros(h,w);
            % 规定线性系数
            a=100; b=200; mf=255;
            c=50; d=230; mg=255;
            
            for i = 1:h
                for j = 1:w
                    if(gray_image(i,j)<a && gray_image(i,j)>=0)
                        advanced_image(i,j) = round(c*gray_image(i,j)/a);
                    elseif(gray_image(i,j)<b && gray_image(i,j)>=a)
                        advanced_image(i,j) = round(((d-c)/(b-a))*(gray_image(i,j)-a)+c);
                    elseif(gray_image(i,j)<=mf && gray_image(i,j)>=b)
                        advanced_image(i,j) = round(((mg-d)/(mf-b))*(gray_image(i,j)-b)+d);
                    end
                end
            end
        end
        
        % 对数变换对比度增强
        function advanced_image = to_enhanced_log(~,gray_image)
            image = double(gray_image);
            advanced_image = log(image+1);
        end
        
        % 指数变换对比度增强
        function advanced_image = to_enhanced_index(~,gray_image)
            image = double(gray_image);
            advanced_image = exp(0.325 * (image-255)/30) + 1;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Input_Button
        function Input_ButtonPushed(app, event)
            % 获取文本框中的路径
            filePath = "E:\course\Digital Image Processing\work\image.png";
            % 检查路径是否为空或无效
            if isempty(filePath) || ~exist(filePath, 'file')
                uialert(app.UIFigure, 'Error', 'Please enter a valid file path.');
                return;
            end
            
            % 读取图片
            try
                image = imread(filePath);
            catch ME
                uialert(app.UIFigure, 'Error', 'Failed to read the image file.');
                return;
            end
            
            % 存储原始图片
            app.img = image;
            app.img_gray = rgb2gray(image);
            % 在UIAxes上显示图片
            imshow(app.img, 'Parent', app.UIAxes_Image);
            title(app.UIAxes_Image, '原始图像');
        end

        % Selection changed function: ButtonGroup_Histogram
        function ButtonGroup_HistogramSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Histogram.SelectedObject;
            % 刷新界面
            reflush_histogram(app,1);
 
            switch selected_button
                case app.Histogram_Equalization
                    % 直方图均衡化
                    if(isempty(app.histogram_equalized))
                        [app.img_equalized,app.histogram_equalized] = to_histogram_equalized(app,app.img_gray);
                        disp(app.img_equalized);
                    end
                    
                    % 显示选项栏和图片
                    
                    
                    imshow(app.img_equalized, 'Parent', app.UIAxes_Histogram_Image);
                    title(app.UIAxes_Histogram_Image, '均衡化——灰度图');
                    
                    stem(app.UIAxes_Histogram_Histogram, app.histogram_equalized)
                    title(app.UIAxes_Histogram_Histogram, '均衡化——灰度直方图');
                    xlabel(app.UIAxes_Histogram_Histogram, '灰度值');
                    ylabel(app.UIAxes_Histogram_Histogram, '像素数量');
                    
                case app.Histogram_Perspective
                    % 直方图规定化
                    if(isempty(app.Histogram_Perspected))
                        Histogram_perspected(app);
                    end
                   
                    % 获取输入的参考图片
                    img_ref = rgb2gray(imread("E:\course\Digital Image Processing\test\2.png"));%app.Input_Histogram_ReferImage.Value));
                    histogram_ref = imhist(img_ref);
                    
                    % 显示选项栏和图片
                    app.UIAxes_Histogram_Grayimage2.Visible = true;app.UIAxes_Histogram2.Visible = true;
                    app.UIAxes_Histogram_Grayimage.Visible = true;app.UIAxes_Histogram_Image.Visible = true;
                    app.Input_Histogram_ReferImage.Visible = true;
                    
                    imshow(app.Img_Perspected, 'Parent', app.UIAxes_Histogram_Grayimage2);
                    title(app.UIAxes_Histogram_Grayimage2, '规定化——灰度图');
                    stem(app.UIAxes_Histogram2,app.Histogram_Perspected);
                    title(app.UIAxes_Histogram2, '规定化——灰度直方图');
                    xlabel(app.UIAxes_Histogram2, '灰度值');
                    ylabel(app.UIAxes_Histogram2, '像素数量');
                    
                    imshow(img_ref, 'Parent', app.UIAxes_Histogram_Grayimage);
                    title(app.UIAxes_Histogram_Grayimage, '参照——灰度图');
                    stem(app.UIAxes_Histogram_Image, histogram_ref);
                    title(app.UIAxes_Histogram_Image, '参照——灰度直方图');
                    xlabel(app.UIAxes_Histogram_Image, '灰度值');
                    ylabel(app.UIAxes_Histogram_Image, '像素数量');
                    
                case app.Histogram_Origin
                    % 灰度图
                    if(isempty(app.Histogram))
                        
                    end
                    
                    % 显示选项栏和图片
                      
            end
            
        end

        % Selection changed function: ButtonGroup_Selection
        function ButtonGroup_SelectionSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Selection.SelectedObject;
            reflush(app);
            
            switch selected_button
                case app.Histogram
                    reflush_histogram(app,1);      
                    % 灰度图(默认操作)
                    
                    % 显示选项栏和图片
                case app.Contrast
                    reflush_contrast(app,1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.UIAxes_Contrast_Advanceimage = uiaxes(app.UIFigure);
            title(app.UIAxes_Contrast_Advanceimage, '变换后灰度图')
            app.UIAxes_Contrast_Advanceimage.XTick = [];
            app.UIAxes_Contrast_Advanceimage.YTick = [];
            app.UIAxes_Contrast_Advanceimage.Visible = 'off';
            app.UIAxes_Contrast_Grayimage = uiaxes(app.UIFigure);
            title(app.UIAxes_Contrast_Grayimage, '灰度图')
            app.UIAxes_Contrast_Grayimage.XTick = [];
            app.UIAxes_Contrast_Grayimage.YTick = [];
            app.UIAxes_Contrast_Grayimage.Visible = 'off';
            app.UIAxes_Histogram_Histogram = uiaxes(app.UIFigure);
            title(app.UIAxes_Histogram_Histogram, '直方图')
            app.UIAxes_Histogram_Histogram.XTick = [];
            app.UIAxes_Histogram_Histogram.YTick = [];
            app.UIAxes_Histogram_Histogram.Visible = 'off';
            app.UIAxes_Histogram_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_Histogram_Image, '灰度图')
            app.UIAxes_Histogram_Image.XTick = [];
            app.UIAxes_Histogram_Image.YTick = [];
            app.UIAxes_Histogram_Image.Visible = 'off';
            app.UIAxes_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_Image, '原图')
            app.UIAxes_Image.XTick = [];
            app.UIAxes_Image.YTick = [];
            app.Input_Histogram_ReferImage = uieditfield(app.UIFigure, 'text');
            app.Input_Histogram_ReferImage.Visible = 'off';
            app.Input_Histogram_ReferImage.Placeholder = '请输入参照图片地址……';
            app.Input_Button = uibutton(app.UIFigure, 'push');
            app.Input_Button.ButtonPushedFcn = createCallbackFcn(app, @Input_ButtonPushed, true);
            app.Input_Button.Text = '上传';
            app.Input_Label = uilabel(app.UIFigure);
            app.Input_Label.Text = '图片地址：';
            app.ButtonGroup_Selection = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Selection.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_SelectionSelectionChanged, true);
            app.ButtonGroup_Selection.Title = '选择处理方式：';
            app.Button_3 = uitogglebutton(app.ButtonGroup_Selection);
            app.Button_3.Text = '几何变换';
            app.Button_4 = uitogglebutton(app.ButtonGroup_Selection);
            app.Button_4.Text = '噪声处理';
            app.Button_5 = uitogglebutton(app.ButtonGroup_Selection);
            app.Button_5.Text = '边缘提取';
            app.Button_6 = uitogglebutton(app.ButtonGroup_Selection);
            app.Button_6.Text = '特征提取';
            app.Contrast = uitogglebutton(app.ButtonGroup_Selection);
            app.Contrast.Text = '对比度增强';
            app.Histogram = uitogglebutton(app.ButtonGroup_Selection);
            app.Histogram.Text = {'灰度直方图'; ''};
            app.Histogram.Value = true;
            app.ButtonGroup_Histogram = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Histogram.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_HistogramSelectionChanged, true);
            app.ButtonGroup_Histogram.Title = '选择：';
            app.ButtonGroup_Histogram.Visible = 'off';
            app.Histogram_Perspective = uitogglebutton(app.ButtonGroup_Histogram);
            app.Histogram_Perspective.Tag = 'Equalization';
            app.Histogram_Perspective.Text = '直方图规定化';
            app.Histogram_Equalization = uitogglebutton(app.ButtonGroup_Histogram);
            app.Histogram_Equalization.Tag = 'Equalization';
            app.Histogram_Equalization.Text = '直方图均衡化';
            app.Histogram_Origin = uitogglebutton(app.ButtonGroup_Histogram);
            app.Histogram_Origin.Tag = 'Origin';
            app.Histogram_Origin.Text = '灰度图';
            app.Histogram_Origin.Value = true;
            app.Input_Image = uieditfield(app.UIFigure, 'text');
            app.ButtonGroup_Contrast = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Contrast.Title = '选择：';
            app.ButtonGroup_Contrast.Visible = 'off';
            app.Contrast_Index = uitogglebutton(app.ButtonGroup_Contrast);
            app.Contrast_Index.Tag = 'Equalization';
            app.Contrast_Index.Text = '指数变换';
            app.Contrast_Log = uitogglebutton(app.ButtonGroup_Contrast);
            app.Contrast_Log.Tag = 'Equalization';
            app.Contrast_Log.Text = '对数变换';
            app.Contrast_Linear = uitogglebutton(app.ButtonGroup_Contrast);
            app.Contrast_Linear.Tag = 'Origin';
            app.Contrast_Linear.Text = '线性处理';
            app.Contrast_Linear.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end